classdef simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartButton                     matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        HeightControlModeButtonGroup    matlab.ui.container.ButtonGroup
        OffButton                       matlab.ui.control.RadioButton
        OnButton                        matlab.ui.control.RadioButton
        AutoButton                      matlab.ui.control.RadioButton
        DesiredHeightSliderLabel        matlab.ui.control.Label
        DesiredHeightSlider             matlab.ui.control.Slider
        DispenseOnButton                matlab.ui.control.Button
        DesiredHeightEditFieldLabel     matlab.ui.control.Label
        DesiredHeightEditField          matlab.ui.control.NumericEditField
        TankStatusPanel                 matlab.ui.container.Panel
        WarningLevelisLOWLabel          matlab.ui.control.Label
        WarningLevelisHIGHLabel         matlab.ui.control.Label
        ControlModeEditFieldLabel       matlab.ui.control.Label
        ControlModeEditField            matlab.ui.control.EditField
        ActualTankHeightEditFieldLabel  matlab.ui.control.Label
        ActualTankHeightEditField       matlab.ui.control.EditField
        DispenseStatusEditFieldLabel    matlab.ui.control.Label
        DispenseStatusEditField         matlab.ui.control.EditField
        DispenseOffButton               matlab.ui.control.Button
        InflowRatecmsKnobLabel          matlab.ui.control.Label
        InflowRatecmsKnob               matlab.ui.control.Knob
        OutflowRatecmsKnobLabel         matlab.ui.control.Label
        OutflowRatecmsKnob              matlab.ui.control.Knob
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % constants
        initialDesiredHeight = 0;   % cm
        startHeight          = 60;  % cm
        lowWarningLevel      = 20;  % cm
        highWarningLevel     = 90;  % cm
        inletShutoffLevel    = 95;  % cm
        maxHeight            = 100; % cm
        
        % variables
        switchState
        TIMER1
        TIMER2
        Count1
        Count2
    end
    
    methods (Access = private)
        % timer 1: update warning labels, system state, and plot
        function timer_callback_fcn1(app, ~, ~, ~)
            persistent heightLogger
            app.Count1 = app.Count1 + 1; % s
            heightLogger(app.Count1,:) = [app.Count1 str2double(app.ActualTankHeightEditField.Value)];
            
            warningLabel(app)             % warning label update
            stateController(app)          % check state
            plotCreate(app, heightLogger) % plot creation
        end
        
        % timer 2: check tank status and update height
        function timer_callback_fcn2(app, ~, ~, ~)
            switch app.switchState
                case 'controlOndispenseOn'
                    if str2double(app.ActualTankHeightEditField.Value) < app.inletShutoffLevel
                        inletFlow = app.InflowRatecmsKnob.Value; % cm/s
                    else
                        inletFlow = 0; % cm/s
                    end
                    outletFlow = app.OutflowRatecmsKnob.Value; % cm/s
                
                case 'controlOffdispenseOn'
                    inletFlow = 0; % cm/s
                    outletFlow = app.OutflowRatecmsKnob.Value; % cm/s
                
                case 'controlAutodispenseOn'
                    if str2double(app.ActualTankHeightEditField.Value) < app.inletShutoffLevel
                        delta = app.DesiredHeightEditField.Value - str2double(app.ActualTankHeightEditField.Value); % cm
                        if delta > .5
                            inletFlow = app.InflowRatecmsKnob.Value; % cm/s
                        else
                            inletFlow = 0; % cm/s
                        end
                    else
                        inletFlow = 0; % cm/s
                    end
                    outletFlow = app.OutflowRatecmsKnob.Value; % cm/s
                
                case 'controlOndispenseOff'
                    if str2double(app.ActualTankHeightEditField.Value) < app.inletShutoffLevel
                        inletFlow = app.InflowRatecmsKnob.Value; % cm/s
                    else
                        inletFlow = 0; % cm/s
                    end
                    outletFlow = 0; % cm/s
                
                case 'controlOffdispenseOff'
                    inletFlow = 0; % cm/s
                    outletFlow = 0; % cm/s
                
                case 'controlAutodispenseOff'
                    if str2double(app.ActualTankHeightEditField.Value) < app.inletShutoffLevel
                        delta = app.DesiredHeightEditField.Value - str2double(app.ActualTankHeightEditField.Value);
                        if delta > .5
                            inletFlow = app.InflowRatecmsKnob.Value; % cm/s
                        else
                            inletFlow = 0; % cm/s
                        end
                    else
                        inletFlow = 0; % cm/s
                    end
                    outletFlow = 0; % cm/s
                
                otherwise
                    inletFlow = 0; % cm/s
                    outletFlow = 0; % cm/s
            end
            
            % return results
            % set tank lower bound to 0 cm
            newHeight = str2double(app.ActualTankHeightEditField.Value) + inletFlow - outletFlow; % cm
            if newHeight < 0
                app.ActualTankHeightEditField.Value = string(0); % cm
            else
                app.ActualTankHeightEditField.Value = string(newHeight); % cm
            end
        end
        
        % warning label controller
        function warningLabel(app)
            % level > 90 cm is HIGH
            % level < 20 cm is LOW
            if str2double(app.ActualTankHeightEditField.Value) > app.highWarningLevel
                app.WarningLevelisHIGHLabel.Visible = 'on';
                app.WarningLevelisLOWLabel.Visible = 'off';
            elseif str2double(app.ActualTankHeightEditField.Value) < app.lowWarningLevel
                app.WarningLevelisLOWLabel.Visible = 'on';
                app.WarningLevelisHIGHLabel.Visible = 'off';
            else
                app.WarningLevelisHIGHLabel.Visible = 'off';
                app.WarningLevelisLOWLabel.Visible = 'off';
            end
        end
        
        % check and return state
        function stateController(app)
            if isequal(app.ControlModeEditField.Value, 'On') && isequal(app.DispenseStatusEditField.Value, 'On')
                app.switchState = 'controlOndispenseOn';
            elseif isequal(app.ControlModeEditField.Value, 'Off') && isequal(app.DispenseStatusEditField.Value, 'On')
                app.switchState = 'controlOffdispenseOn';
            elseif isequal(app.ControlModeEditField.Value, 'Auto') && isequal(app.DispenseStatusEditField.Value, 'On')
                app.switchState = 'controlAutodispenseOn';
            elseif isequal(app.ControlModeEditField.Value, 'On') && isequal(app.DispenseStatusEditField.Value, 'Off')
                app.switchState = 'controlOndispenseOff';
            elseif isequal(app.ControlModeEditField.Value, 'Auto') && isequal(app.DispenseStatusEditField.Value, 'Off')
                app.switchState = 'controlAutodispenseOff';
            else 
                app.switchState = 'controlOffdispenseOff';
            end
        end
        
        % create plot of height vs time
        function plotCreate(app, y)
            plot(app.UIAxes, y(:,2))
            app.UIAxes.YLim = [0 app.maxHeight];
            
            % only display last 60 sec of operation
            if length(y(:,2)) < 60
                app.UIAxes.XLim = [0 60];
            else
                app.UIAxes.XLim = [length(y(:,2))-60 length(y(:,2))];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize values
            app.ActualTankHeightEditField.Value = string(app.startHeight);
            app.DesiredHeightSlider.Value       = app.initialDesiredHeight;  % cm
            app.DesiredHeightEditField.Value    = app.initialDesiredHeight;  % cm
            app.ControlModeEditField.Value      = app.HeightControlModeButtonGroup.SelectedObject.Text;
            app.DispenseStatusEditField.Value   = 'Off';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % component interactivity
            app.StartButton.Enable                  = 'off';
            app.ExitButton.Enable                   = 'on';
            app.DispenseOnButton.Enable             = 'on';
            app.HeightControlModeButtonGroup.Enable = 'on';
            app.DesiredHeightSlider.Enable          = 'on';
            app.DesiredHeightSliderLabel.Enable     = 'on';
            app.DesiredHeightEditField.Editable     = 'on';
            app.DesiredHeightEditField.Enable       = 'on';
            app.DesiredHeightEditFieldLabel.Enable  = 'on';
            app.InflowRatecmsKnob.Enable            = 'on';
            app.InflowRatecmsKnobLabel.Enable       = 'on';
            app.OutflowRatecmsKnob.Enable           = 'on';
            app.OutflowRatecmsKnobLabel.Enable      = 'on';
            
            % start timers
            app.Count1 = 0; % s
            app.Count2 = 0; % s
            app.TIMER1 = timer('TimerFcn', @app.timer_callback_fcn1, 'period', 1.0, 'ExecutionMode', 'fixedRate');
            app.TIMER2 = timer('TimerFcn', @app.timer_callback_fcn2, 'period', 1.0, 'ExecutionMode', 'fixedRate');
            start(app.TIMER1);
            start(app.TIMER2);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            stop(app.TIMER1);
            stop(app.TIMER2);
            delete(app.TIMER1);
            delete(app.TIMER2);
            delete(app);
        end

        % Button pushed function: DispenseOnButton
        function DispenseOnButtonPushed(app, event)
            app.DispenseOnButton.Enable       = 'off';
            app.DispenseOffButton.Enable      = 'on';
            app.DispenseStatusEditField.Value = 'On';
        end

        % Button pushed function: DispenseOffButton
        function DispenseOffButtonPushed(app, event)
            app.DispenseOffButton.Enable      = 'off';
            app.DispenseOnButton.Enable       = 'on';
            app.DispenseStatusEditField.Value = 'Off';
        end

        % Value changed function: DesiredHeightSlider
        function DesiredHeightSliderValueChanged(app, event)
            app.DesiredHeightEditField.Value = app.DesiredHeightSlider.Value;
        end

        % Value changed function: DesiredHeightEditField
        function DesiredHeightEditFieldValueChanged(app, event)
            app.DesiredHeightSlider.Value = app.DesiredHeightEditField.Value;
        end

        % Selection changed function: HeightControlModeButtonGroup
        function HeightControlModeButtonGroupSelectionChanged(app, event)
            app.ControlModeEditField.Value = app.HeightControlModeButtonGroup.SelectedObject.Text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Text = 'Exit';
            app.HeightControlModeButtonGroup = uibuttongroup(app.UIFigure);
            app.HeightControlModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HeightControlModeButtonGroupSelectionChanged, true);
            app.HeightControlModeButtonGroup.Enable = 'off';
            app.HeightControlModeButtonGroup.Title = 'Height Control Mode';
            app.OffButton = uiradiobutton(app.HeightControlModeButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.OnButton = uiradiobutton(app.HeightControlModeButtonGroup);
            app.OnButton.Text = 'On';
            app.AutoButton = uiradiobutton(app.HeightControlModeButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.DesiredHeightSliderLabel = uilabel(app.UIFigure);
            app.DesiredHeightSliderLabel.Enable = 'off';
            app.DesiredHeightSliderLabel.Text = 'Desired Height';
            app.DesiredHeightSlider = uislider(app.UIFigure);
            app.DesiredHeightSlider.ValueChangedFcn = createCallbackFcn(app, @DesiredHeightSliderValueChanged, true);
            app.DesiredHeightSlider.Enable = 'off';
            app.DispenseOnButton = uibutton(app.UIFigure, 'push');
            app.DispenseOnButton.ButtonPushedFcn = createCallbackFcn(app, @DispenseOnButtonPushed, true);
            app.DispenseOnButton.Enable = 'off';
            app.DispenseOnButton.Text = 'Dispense On';
            app.DesiredHeightEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredHeightEditFieldLabel.Enable = 'off';
            app.DesiredHeightEditFieldLabel.Text = 'Desired Height';
            app.DesiredHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredHeightEditField.Limits = [0 100];
            app.DesiredHeightEditField.ValueDisplayFormat = '%4.4g';
            app.DesiredHeightEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredHeightEditFieldValueChanged, true);
            app.DesiredHeightEditField.Editable = 'off';
            app.DesiredHeightEditField.Enable = 'off';
            app.TankStatusPanel = uipanel(app.UIFigure);
            app.TankStatusPanel.Title = 'Tank Status';
            app.WarningLevelisLOWLabel = uilabel(app.TankStatusPanel);
            app.WarningLevelisLOWLabel.Text = 'Warning: Level is LOW';
            app.WarningLevelisHIGHLabel = uilabel(app.TankStatusPanel);
            app.WarningLevelisHIGHLabel.Text = 'Warning: Level is HIGH';
            app.ControlModeEditFieldLabel = uilabel(app.TankStatusPanel);
            app.ControlModeEditFieldLabel.Text = 'Control Mode';
            app.ControlModeEditField = uieditfield(app.TankStatusPanel, 'text');
            app.ControlModeEditField.Editable = 'off';
            app.ActualTankHeightEditFieldLabel = uilabel(app.TankStatusPanel);
            app.ActualTankHeightEditFieldLabel.Text = 'Actual Tank Height';
            app.ActualTankHeightEditField = uieditfield(app.TankStatusPanel, 'text');
            app.ActualTankHeightEditField.Editable = 'off';
            app.DispenseStatusEditFieldLabel = uilabel(app.TankStatusPanel);
            app.DispenseStatusEditFieldLabel.Text = 'Dispense Status';
            app.DispenseStatusEditField = uieditfield(app.TankStatusPanel, 'text');
            app.DispenseStatusEditField.Editable = 'off';
            app.DispenseOffButton = uibutton(app.UIFigure, 'push');
            app.DispenseOffButton.ButtonPushedFcn = createCallbackFcn(app, @DispenseOffButtonPushed, true);
            app.DispenseOffButton.Enable = 'off';
            app.DispenseOffButton.Text = 'Dispense Off';
            app.InflowRatecmsKnobLabel = uilabel(app.UIFigure);
            app.InflowRatecmsKnobLabel.Enable = 'off';
            app.InflowRatecmsKnobLabel.Text = {'Inflow Rate'; '(cm/s)'};
            app.InflowRatecmsKnob = uiknob(app.UIFigure, 'continuous');
            app.InflowRatecmsKnob.Limits = [0 2];
            app.InflowRatecmsKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.InflowRatecmsKnob.Enable = 'off';
            app.OutflowRatecmsKnobLabel = uilabel(app.UIFigure);
            app.OutflowRatecmsKnobLabel.Enable = 'off';
            app.OutflowRatecmsKnobLabel.Text = {'Outflow Rate'; '(cm/s)'};
            app.OutflowRatecmsKnob = uiknob(app.UIFigure, 'continuous');
            app.OutflowRatecmsKnob.Limits = [0 1];
            app.OutflowRatecmsKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.OutflowRatecmsKnob.Enable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tank Height')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Height (cm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTickLabel = {'0'; '50'; '100'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end